classdef Connection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Text                     matlab.ui.control.TextArea
        GridLayout3              matlab.ui.container.GridLayout
        SerialPortDropDownLabel  matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        GridLayout4              matlab.ui.container.GridLayout
        ConnectButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        ArduinoConnection   % This is the arduino connection
    end
    
    properties (Access = private)
        CallingApp  % This is the app which calls the Connection_Window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.SerialPortDropDown.Items = seriallist;  % Update from the DropDown Items with the COM Ports
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            delete(instrfindall);
            com_port = app.SerialPortDropDown.Value;
            
            if ~exist(app.ArduinoConnection)           
                try
                    delete(instrfind({'Port'},{com_port}));
                    app.ArduinoConnection = serialport(com_port,'BaudRate',9600,'Terminator','CR/LF');
                catch
                    errordlg('Arduino connection failed');
                end
            end
            
            if exist(app.ArduinoConnection)
                % Call main app's public connection function
                Connect(app.CallingApp, app.ArduinoConnection);
                msgbox('Arduino already connected');
                delete(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ConnectButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Text = uitextarea(app.GridLayout);
            app.Text.Value = {'Select the port where you want to connect:'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SerialPortDropDownLabel = uilabel(app.GridLayout3);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.GridLayout3);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ConnectButton = uibutton(app.GridLayout4, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Connection_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end