classdef app3GDL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SetHomeButton    matlab.ui.control.Button
        GoHomeButton     matlab.ui.control.Button
        q1SliderLabel    matlab.ui.control.Label
        q1Slider         matlab.ui.control.Slider
        q2SliderLabel    matlab.ui.control.Label
        q2Slider         matlab.ui.control.Slider
        q3SliderLabel    matlab.ui.control.Label
        q3Slider         matlab.ui.control.Slider
        ConnectButton    matlab.ui.control.Button
        MoveButton       matlab.ui.control.Button
        PickPlaceButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        a % Arduino Property
        Connection_App % This is the Connection Window
        Message % The message that will be comunicating matlab with arduino
    end
    
    methods (Access = private)
        
        function Disconnect(app)
            if exist(app.a)
                % Close the connection with Arduino
                fclose(app.a);
                delete(app.a);
                clear app.a;
            end
            % We print a message of disconnection
            msgbox('Arduino has been disconnected')
            
            % We change the Disconnection button to Connection button
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton.BackgroundColor = 'g';
            
            % We enable the pressing of the connection button
            app.ConnectButton.Enable = 'on';
        end
        
        function EnableAll(app)
           % We make everything enable
           app.SetHomeButton.Enable = 'on';
           app.GoHomeButton.Enable = 'on';
           app.q1Slider.Enable = 'on';
           app.q2Slider.Enable = 'on';
           app.q3Slider.Enable = 'on';
           app.MoveButton.Enable = 'on';
           app.PickPlaceButton.Enable = 'on';
            
        end
        
        
        function UnableAll(app)
           % We make everything unable
           app.SetHomeButton.Enable = 'off';
           app.GoHomeButton.Enable = 'off';
           app.q1Slider.Enable = 'off';
           app.q2Slider.Enable = 'off';
           app.q3Slider.Enable = 'off';
           app.MoveButton.Enable = 'off';
           app.PickPlaceButton.Enable = 'off';
        end
 end
    
    methods (Access = public)
        
         
        % This Public Function must be called by the Connection Window for
        % setting up the connection from Arduino
        function Connect(app,ArduinoConnection)
            
            % We stablish the Arduino Connection
            app.a = ArduinoConnection;
            % Abrimos el puerto serie
            fopen(app.a);
            % Eliminamos posibles datos recibidos hasta ahora
            flushinput(app.a)
            % We change the Connection button to Disconnection button
            app.ConnectButton.Text = 'Disconnect';
            app.ConnectButton.BackgroundColor = 'r';
            
            % We enable the pressing of the connection button
            app.ConnectButton.Enable = 'on';
            
            % We make the preparations for start the program
            EnableAll(app);
            
        end
        
         %%%%%%%%%%%%%%%%%%%% Main function %%%%%%%%%%%%%%%%%%%%
        function loop(app)
            
            % We make an infinite loop
            while (1 == 1)
                % We enable the switch
                app.Switch.Enable = 'on';
                
                % We chose the mode
                if(app.mode == 0)
                    RandomMode(app)
                else
                    ChosingMode(app)
                end
            end
        end
        
        
        % Function that takes action when we do not receive the expecte
        function NotAnswer(app)
            msgbox('Arduino does not answer');
            Disconnect(app);
        end
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %%%%%%%%%% Robot generation %%%%%%%%%%
           
           % We apply the Denavit table
           L1=Link([0 150 0 pi/2]);
           L1.qlim = [-pi/2 pi/2];
           L2=Link([0 0 155 0]);
           L2.offset=pi/2;
           L3=Link([0 0 200 0]);

           
           L=[L1 L2 L3];
           % We generate the robot
           robot=SerialLink(L, 'name', 'P2');
           % We represent the offset between the grip and the extrem of the
           % robot
           robot.tool=transl(65, 45, 0);
    
           % We stablish the origin at 0,0,0
           q=[0 0 0];
           
           % Then we plot the robot
           robot.plot(q);
           
           %%%%%%%%%% Setup of physical objects %%%%%%%%%%
           
           % We make everything unable, until we get a connection with
           % arduino
           UnableAll(app)
           
           
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
           
            % We generate the message
            app.Message = "moveToAngles(";
            app.Message = app.Message + app.q1Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q2Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q3Slider.Value;
            app.Message = app.Message + ")";
            
            % And we send the message
            fprintf(app.a,app.Message);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % First of all we disable the button
            app.ConnectButton.Enable = 'off';
            
            % We must comprobate if the button is a disconnection button or
            % a connection button
            if strcmpi(app.ConnectButton.Text,"Connect") || (app.ConnectButton.BackgroundColor == 'g')
                app.Connection_App = Connection_Window(app);
            else
                Disconnect(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist(app.a)
                Disconnect(app)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetHomeButton = uibutton(app.UIFigure, 'push');
            app.SetHomeButton.Text = 'Set Home';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.Text = 'Go Home';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PickPlaceButton = uibutton(app.UIFigure, 'push');
            app.PickPlaceButton.Text = 'Pick&Place';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3GDL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end