classdef app3GDL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SetHomeButton    matlab.ui.control.Button
        GoHomeButton     matlab.ui.control.Button
        q1SliderLabel    matlab.ui.control.Label
        q1Slider         matlab.ui.control.Slider
        q2SliderLabel    matlab.ui.control.Label
        q2Slider         matlab.ui.control.Slider
        q3SliderLabel    matlab.ui.control.Label
        q3Slider         matlab.ui.control.Slider
        MoveButton       matlab.ui.control.Button
        ConnectButton    matlab.ui.control.Button
        PickPlaceButton  matlab.ui.control.Button
        resetq1Button    matlab.ui.control.Button
        resetq2Button_2  matlab.ui.control.Button
        resetq3Button_3  matlab.ui.control.Button
    end

    
    properties (Access = private)
        a % Arduino Property
        Connection_App % This is the Connection Window
        Message % The message that will be comunicating matlab with arduino
        Limits % A boolean wich stores if the limits for the steppers are stablished
        aux % An auxiliar variable that we will use for storing some data
    end
    
    properties (Access = public)
        Robot % This property will store the representation of our robot
    end
    
    methods (Access = private)
        
        function Disconnect(app)
            if exist(app.a)
                % Close the connection with Arduino
                fclose(app.a);
                delete(app.a);
                clear app.a;
            end
            % We print a message of disconnection
            msgbox('Arduino has been disconnected')
            
            % We change the Disconnection button to Connection button
            app.ConnectButton.Text = 'Connect';
            app.ConnectButton.BackgroundColor = 'g';
            
            % We enable the pressing of the connection button
            app.ConnectButton.Enable = 'on';
        end
        
        function EnableAll(app)
           % We make everything enable
           app.SetHomeButton.Enable = 'on';
           app.GoHomeButton.Enable = 'on';
           app.q1Slider.Enable = 'on';
           app.q2Slider.Enable = 'on';
           app.q3Slider.Enable = 'on';
           app.MoveButton.Enable = 'on';
           app.PickPlaceButton.Enable = 'on';
           app.q1SliderLabel.Enable = 'on';
           app.q2SliderLabel.Enable = 'on';
           app.q3SliderLabel.Enable = 'on';
            
        end
        
        
        function UnableAll(app)
           % We make everything unable
           app.SetHomeButton.Enable = 'off';
           app.GoHomeButton.Enable = 'off';
           app.q1Slider.Enable = 'off';
           app.q2Slider.Enable = 'off';
           app.q3Slider.Enable = 'off';
           app.MoveButton.Enable = 'off';
           app.PickPlaceButton.Enable = 'off';
           app.resetq1Button.Enable = 'off';
           app.resetq2Button_2.Enable = 'off';
           app.resetq3Button_3.Enable = 'off';
           app.q1SliderLabel.Enable = 'off';
           app.q2SliderLabel.Enable = 'off';
           app.q3SliderLabel.Enable = 'off';
        end
 end
    
    methods (Access = public)
        
         
        % This Public Function must be called by the Connection Window for
        % setting up the connection from Arduino
        function Connect(app,ArduinoConnection)
            
            % We stablish the Arduino Connection
            app.a = ArduinoConnection;
            % Abrimos el puerto serie
            fopen(app.a);
            % Eliminamos posibles datos recibidos hasta ahora
            flushinput(app.a)
            % We change the Connection button to Disconnection button
            app.ConnectButton.Text = 'Disconnect';
            app.ConnectButton.BackgroundColor = 'r';
            
            % We enable the pressing of the connection button
            app.ConnectButton.Enable = 'on';
            
            % We make the preparations for start the program
            
            % We enable the reset buttons
            app.resetq1Button.Enable = 'on';
            app.resetq2Button_2.Enable = 'on';
            app.resetq3Button_3.Enable = 'on';
            
            
        end
        
         %%%%%%%%%%%%%%%%%%%% Main function %%%%%%%%%%%%%%%%%%%%
        function loop(app)
            
            % We make an infinite loop
            while (1 == 1)
                % We wait until we receive some answer from Arduino
                while (app.a.NumBytesAvailable < 0)
                end
                app.Message = fgetl(app.a);
                if strcmpi(app.Message,"OK") && limits(1) == 1 && limits(2) == 1 && limits(3) == 1
                    EnableAll(app);
                end
            end
        end
        
        
        % Function that takes action when we do not receive the expecte
        function NotAnswer(app)
            msgbox('Arduino does not answer');
            Disconnect(app);
        end
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %%%%%%%%%% Robot generation %%%%%%%%%%
           
           % We apply the Denavit table
           L1=Link([0 150 0 pi/2]);
           L1.qlim = [-pi/2 pi/2];
           L2=Link([0 0 155 0]);
           L2.offset=pi/2;
           L3=Link([0 0 200 0]);

           
           L=[L1 L2 L3];
           % We generate the robot
           app.Robot=SerialLink(L, 'name', 'P2');
           % We represent the offset between the grip and the extrem of the
           % robot
           app.Robot.tool=transl(65, 45, 0);
    
           % We stablish the origin at 0,0,0
           q=[0 0 0];
           
           % Then we plot the robot
           app.Robot.plot(q);
           
           %%%%%%%%%% Setup of variables %%%%%%%%%%
           app.Message = "";
           app.Limits = [0 0 0];
           
           %%%%%%%%%% Setup of physical objects %%%%%%%%%%
           
           % We make everything unable, until we get a connection with
           % arduino
           UnableAll(app);
           
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
           
            % We generate the message
            app.Message = "moveToAngles(";
            app.Message = app.Message + app.q1Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q2Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q3Slider.Value;
            app.Message = app.Message + ")";
            
            % And we send the message
            fprintf(app.a,app.Message);
            UnableAll(app);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % First of all we disable the button
            app.ConnectButton.Enable = 'off';
            
            % We must comprobate if the button is a disconnection button or
            % a connection button
            if strcmpi(app.ConnectButton.Text,"Connect") || (app.ConnectButton.BackgroundColor == 'g')
                app.Connection_App = Connection_Window(app);
            else
                Disconnect(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist(app.a)
                Disconnect(app)
            end
            delete(app)
        end

        % Button pushed function: resetq1Button
        function resetq1ButtonPushed(app, event)
            % We make sure that the user does not touchs anything
            UnableAll(app);
            
            % We send the message to the arduino
            fprintf(app.a,"reset_stepper0");
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We store the answer into the aux variable
            app.aux = app.Message;
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We compare both answers and stablish the limits of the slider
            if(app.Message < app.aux)
                app.q1Slider.Limits = [rad2deg(app.Message) rad2deg(app.aux)];
            else
                app.q1Slider.Limits = [rad2deg(app.aux) rad2deg(app.Message)];
            end
            
            % We make as true the boolean
            app.Limits(1) = 1;
            
            % We enable all the reset buttons
            app.resetq1Button.Enable = 'on';
            app.resetq2Button_2.Enable = 'on';
            app.resetq3Button_3.Enable = 'on';
            
            % We go to the loop
            loop(app);
            
        end

        % Button pushed function: resetq2Button_2
        function resetq2Button_2Pushed(app, event)
            % We make sure that the user does not touchs anything
            UnableAll(app);
            
            % We send the message to the arduino
            fprintf(app.a,"reset_stepper1");
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We store the answer into the aux variable
            app.aux = app.Message;
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We compare both answers and stablish the limits of the slider
            if(app.Message < app.aux)
                app.q2Slider.Limits = [rad2deg(app.Message) rad2deg(app.aux)];
            else
                app.q2Slider.Limits = [rad2deg(app.aux) rad2deg(app.Message)];
            end
            
            % We make as true the boolean
            app.Limits(2) = 1;
            
            % We enable all the reset buttons
            app.resetq1Button.Enable = 'on';
            app.resetq2Button_2.Enable = 'on';
            app.resetq3Button_3.Enable = 'on';
            
            % We go to the loop
            loop(app);
        end

        % Button pushed function: resetq3Button_3
        function resetq3Button_3Pushed(app, event)
            % We make sure that the user does not touchs anything
            UnableAll(app);
            
            % We send the message to the arduino
            fprintf(app.a,"reset_stepper2");
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We store the answer into the aux variable
            app.aux = app.Message;
            
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            
            % We compare both answers and stablish the limits of the slider
            if(app.Message < app.aux)
                app.q3Slider.Limits = [rad2deg(app.Message) rad2deg(app.aux)];
            else
                app.q3Slider.Limits = [rad2deg(app.aux) rad2deg(app.Message)];
            end
            
            % We make as true the boolean
            app.Limits(3) = 1;
            
            % We enable all the reset buttons
            app.resetq1Button.Enable = 'on';
            app.resetq2Button_2.Enable = 'on';
            app.resetq3Button_3.Enable = 'on';
            
            % We go to the loop
            loop(app);
        end

        % Button pushed function: SetHomeButton
        function SetHomeButtonPushed(app, event)
            % We make sure that the user does not touchs anything
            UnableAll(app);
            
            % We make the message
            app.Message = "setHome(";
            app.Message = app.Message + app.q1Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q2Slider.Value;
            app.Message = app.Message + ",";
            app.Message = app.Message + app.q3Slider.Value;
            app.Message = app.Message + ")";
            
            % We send the message to the arduino
            fprintf(app.a,app.Message);
            
            % We return to the loop
            loop(app);
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            % We make sure that the user does not touchs anything
            UnableAll(app);
            
            % We send the message to the arduino
            fprintf(app.a,"goHome");
            
            % We return to the loop
            loop(app);
        end

        % Button pushed function: PickPlaceButton
        function PickPlaceButtonPushed(app, event)
            % We make sure that the user does not touch anything
            UnableAll(app);
            
            % We send the message to the arduino
            fprintf(app.a,"pick&place");
            
            % We wait for the answer
            % We wait for the first answer
            while (app.a.NumBytesAvailable < 0)
            end
            app.Message = fgetl(app.a);
            % We could comprobate the response, but for save time we won't
            % do it
            
            %We simulate the movement in matlab
            q_1 = [deg2rad(67.2) deg2rad(73.5) deg2rad(53.2)];

            q_2 = [deg2rad(31.7) deg2rad(22.8) deg2rad(-17.7)];
            
            q_3=[deg2rad(-52) deg2rad(134) deg2rad(-33)];
            
            qt = mtraj(@lspb, q_1, q_2,100);
            
            qt_2= mtraj(@lspb, q_2, q_3,100);
            robot.plot(qt)
            robot.plot(qt_2)
            
            
            % We send that we want to make the default pick&place
            fprintf(app.a,"y");
            
            % We return to the loop
            loop(app);
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            % We set the articular positions of the robot
            q=[deg2rad(app.q1Slider.Value),deg2rad(app.q2Slider.Value),deg2rad(app.q3Slider.Value)];
            % We represent the movement
            app.Robot.plot(q);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
             % We set the articular positions of the robot
            q=[deg2rad(app.q1Slider.Value),deg2rad(app.q2Slider.Value),deg2rad(app.q3Slider.Value)];
            % We represent the movement
            app.Robot.plot(q);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
             % We set the articular positions of the robot
            q=[deg2rad(app.q1Slider.Value),deg2rad(app.q2Slider.Value),deg2rad(app.q3Slider.Value)];
            % We represent the movement
            app.Robot.plot(q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetHomeButton = uibutton(app.UIFigure, 'push');
            app.SetHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SetHomeButtonPushed, true);
            app.SetHomeButton.Text = 'Set Home';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-90 90];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PickPlaceButton = uibutton(app.UIFigure, 'push');
            app.PickPlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PickPlaceButtonPushed, true);
            app.PickPlaceButton.Text = 'Pick&Place';
            app.resetq1Button = uibutton(app.UIFigure, 'push');
            app.resetq1Button.ButtonPushedFcn = createCallbackFcn(app, @resetq1ButtonPushed, true);
            app.resetq1Button.Text = 'reset';
            app.resetq2Button_2 = uibutton(app.UIFigure, 'push');
            app.resetq2Button_2.ButtonPushedFcn = createCallbackFcn(app, @resetq2Button_2Pushed, true);
            app.resetq2Button_2.Text = 'reset';
            app.resetq3Button_3 = uibutton(app.UIFigure, 'push');
            app.resetq3Button_3.ButtonPushedFcn = createCallbackFcn(app, @resetq3Button_3Pushed, true);
            app.resetq3Button_3.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3GDL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end